classdef Tuner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        E2Button            matlab.ui.control.Button
        A2Button            matlab.ui.control.Button
        D3Button            matlab.ui.control.Button
        G3Button            matlab.ui.control.Button
        B3Button            matlab.ui.control.Button
        E4Button            matlab.ui.control.Button
        RightPanel          matlab.ui.container.Panel
        NOTELabel           matlab.ui.control.Label
        Gauge               matlab.ui.control.SemicircularGauge
        InputsettingButton  matlab.ui.control.Button
        CloseButton         matlab.ui.control.Button
        ContextMenu         matlab.ui.container.ContextMenu
        Menu                matlab.ui.container.Menu
        Menu2               matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        % Hearing Range
        LowestFreq = 20; % in Hz
        HighestFreq = 20000; % in HZ
        
        CurrentNote;
        CurrentFrequency;
        
        Notes = ["C", "C#", "D", "D#", "E", "F", "F#", "G", "G#", "A", "A#", "B"];
        AllFrequencies; % All 128 Frequencies
        AllNotes; % All 128 Notes
        NotesAndFreqs; % 2x128 matrix [AllFrequencies;
                       %               AllNotes]
        Recorder;
        Fs = 48000; % Sampling rate
        
        % Zero Padding for a better frequency resolution
        FsIncr = 2; % Precision of 2 decimal places
        
        CentPrecision = 10;
        NBits = 16;
        NumChannels = 1;
        
        MinPeakHeight = 5e-5; % minimum amplitude for further analysis of the audio signal
        
        % Settings App properties
        DialogApp;
        PlayerID;
        RecorderID;
        RecDuration = 0.5; % recording time
    end
    
    methods (Access = private)
        function onOffComponents(app, status)
            
            app.InputsettingButton.Enable = status;
            % Select Tuning
            app.E2Button.Enable = status;
            app.A2Button.Enable = status;
            app.D3Button.Enable = status;
            app.G3Button.Enable = status;
            app.B3Button.Enable = status;
            app.E4Button.Enable = status;
        end

        function populateFreq(app)
            for i = -1:9
                temp = strcat(app.Notes, num2str(i));
                app.AllNotes = [app.AllNotes temp];
            end
            
            app.AllNotes = app.AllNotes(1:128);                       
            
            for n = 0:127
                % https://www.translatorscafe.com/unit-converter/en-US/calculator/note-frequency/
                app.AllFrequencies(n+1) = round(440*2^((n-69)/12), app.FsIncr);
            end
            
            app.NotesAndFreqs = [app.AllNotes; app.AllFrequencies]; % 2x128 matrix with notes and frequencies
        end
        
        function updateAudioRecorder(app, newMicID)            
            % delete old audiorecorder
            if (~isempty(app.Recorder))
                delete(app.Recorder)
            end
            
            % create new audiorecorder
            app.Recorder = audiorecorder(app.Fs, app.NBits, app.NumChannels, newMicID);           
        end
        
        % https://www.translatorscafe.com/unit-converter/en-US/calculator/note-frequency/
        function results = setRefFreq(app)
            n = find(strcmp(app.AllNotes, app.CurrentNote)) - 1;
            results = 440*2^((n-69)/12);            
        end
        
        function initializeGauge(app, referenceFreq)
            centAbs = 50; % how wide is the scale
            
            app.Gauge.Limits = [centToFreq(app, referenceFreq, -centAbs) centToFreq(app, referenceFreq, centAbs)];
            app.Gauge.MajorTicks = [centToFreq(app, referenceFreq, -centAbs) centToFreq(app, referenceFreq, -centAbs/2) referenceFreq...
                centToFreq(app, referenceFreq, centAbs/2) centToFreq(app, referenceFreq, centAbs)]; % -50 -25 0 25 50 (in cents)
            % 1 cent = 1 MinorTick
            app.Gauge.MinorTicks = linspace(centToFreq(app, referenceFreq, -centAbs), centToFreq(app, referenceFreq, centAbs), centAbs*2);           
        end
        
        % Get the distance between the recorded frequency and the reference
        % frequency. If results < 0, then the frequency is too low, if > 0,
        % then the frequency is too high
        function results = centDistance(~, recordedFreq, referenceFreq)
            results = 1200*log2(recordedFreq/referenceFreq);            
        end
        
        % Get a frequency, that corresponds to a given cent distance using
        % the formula from the function centDistance
        % cents = 1200*log2(x/refFreq)
        % <=> log2(x/refFreq) = cents/1200
        % <=> log2(x) - log2(refFreq) = cents/1200
        % <=> log2(x) = cents/1200 + log2(refFreq)
        % <=> x = 2^(cents/1200 + log2(refFreq))
        function results = centToFreq(app, refFreq, cents)
            results = round(2^(cents/1200 + log2(refFreq)), app.FsIncr);
        end
        
        % Get a frequency, that corresponds to a given note
        function results = note2freq(app, note)
            index = strcmp(note, app.NotesAndFreqs(1,:)) == 1;
            freq = app.NotesAndFreqs(2, index);
            results = round(str2double(freq), app.FsIncr);
        end
        
        function tune(app)
            while true
                try
                    audioSignal = recordData(app);
                    if audioSignal == -1
                        break;
                    end
                    
                    % Windowing avoids the jumps in the periodic signal
                    % when the sampled signal does not have a whole number
                    % of periods
                    audioSignalWindow = audioSignal .* (numel(audioSignal));
                    
                    % Get 2-sided spectrum with Fast Fourier
                    spectrum = fft(audioSignalWindow, app.Fs * 10^app.FsIncr); % *100
                    
                    Nf = numel(spectrum);
                    dF = app.Fs/Nf; % = 0.01
                    
                    % Get 1-sided spectrum
                    spectrum1 = abs([spectrum(1) spectrum(2:Nf/2)' *2]) ./ Nf;
                    
                    % Cut out irrelevant frequencies
                    firstIndex = floor(app.LowestFreq/dF);
                    lastIndex = ceil(app.HighestFreq/dF);
                    spectrum1Cutted = spectrum1;
                    spectrum1Cutted(1:firstIndex) = 0;
                    spectrum1Cutted(lastIndex:end) = 0;
                    
                    % If there are no signigicant bins, then go to the next
                    % iteration
                    maxPeak = max(abs(spectrum1Cutted));
                    if maxPeak < app.MinPeakHeight
                        updateGauge(app, 0);
                        %updatePlusMinusFields(app, 0, app.CurrentFrequency);
                        continue;
                    end
                    
                    % Normalize 1 sided spectrum
                    spectrum1CuttedNorm = spectrum1Cutted/max(spectrum1Cutted);
                    
                    % Get the fundamental frequency
                    f0 = getFundamentalFreq(app, spectrum1CuttedNorm, dF);
                    
                    % Refresh the gauge and the text fields
                    %updatePlusMinusFields(app, f0, app.CurrentFrequency);
                    updateGauge(app, f0);                                
                                   
                catch e
                    waitfor(errordlg(['An error has occurred!' newline e.identifier newline ...
                        e.message], 'Error :('));
                    break;
                end
            end
        end 
        
        function results = recordData(app)
            try
                app.Recorder.record();
                pause(app.RecDuration);
                app.Recorder.stop();
                results = getaudiodata(app.Recorder);
            catch
                results = -1;
            end
        end
        
        function updateGauge(app, recordedFreq)
            app.Gauge.Value = recordedFreq;
        end
        
        % https://www.researchgate.net/publication/45888076_A_Digital_Guitar_Tuner
        function results = getFundamentalFreq(app, spectrum1CuttedNorm, dF)
            addSpectrum = spectrum1CuttedNorm;
            
            % Down Sampling & Spectrum Addition
            for i = 2:5
                tempSpectrum = zeros(1, numel(spectrum1CuttedNorm));
                n = numel(spectrum1CuttedNorm(1:i:end));
                tempSpectrum(1:n) = spectrum1CuttedNorm(1:i:end);
                addSpectrum = addSpectrum + tempSpectrum;
            end
            
            % Get the interval with the fundamental frequency
            reference = app.CurrentFrequency;
            from = mean([reference reference/2]); % half an octave lower
            to = mean([reference reference*2]);   % hald an octave higher
            
            % Cut out not important frequencies
            fromIndex = floor(from/dF);
            toIndex = ceil(to/dF);
            addSpectrum(1:fromIndex) = 0;
            addSpectrum(toIndex:end) = 0;
            
            [~, loc] = max(addSpectrum);
            results = round(loc*dF, app.FsIncr);
        end
        
        function updateTippField(app, centDist)
            if isinf(centDist)
                app.NOTELabel.Visible = 0;
            else
                precision = app.CentPrecision;
                if centDist < -precision
                    app.NOTELabel.Visible = 0;
                elseif centDist > precision
                    app.NOTELabel.Visible = 0;
                else 
                    app.NOTELabel.Visible = 1;
                    app.NOTELabel.Text = app.CurrentNote;
                    app.NOTELabel.FontColor = 'g';
                end
            end
        end
        
        % https://de.mathworks.com/help/signal/ug/generating-guitar-chords-using-the-karplus-strong-algorithm.html
        function playFreq(app, freq)
            ID = app.PlayerID;
            fs = app.Fs;
            nBits = app.NBits;
            duration = 1;
            
            x = zeros(fs*duration, 1);
            delay = round(fs/freq);
            
            b = firls(42, [0 1/delay 2/delay 1], [0 0 1 1]);
            a = [1 zeros(1, delay) -0.5 -0.5];
            
            zi = rand(max(length(b), length(a))-1,1);
            note = filter(b, a, x, zi);
            
            % normalize the sound
            note = note - mean(note);
            note = note / max(abs(note));
            
            hplayer = audioplayer(note, fs, nBits, ID);
            playblocking(hplayer);                    
        end
    end
    
    methods (Access = public)
        function updateFromSettings(app, micID, playerID, recDuration)
            app.RecorderID = micID;
            updateAudioRecorder(app, micID);
            app.PlayerID = playerID;
            app.RecDuration = recDuration;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            onOffComponents (app, 'off');
            
            populateFreq(app); % create 2x128 matrix with all notes and frequencies
            
            dinf = audiodevinfo();
            devID = dinf.input(1).ID; % internal microphone
            app.PlayerID = dinf.output(1).ID; % internal player
            
            % waiting for the user to close the settings
            % https://de.mathworks.com/help/matlab/creating_guis/creating-multiwindow-apps-in-app-designer.html
            waitfor(TunerSetting(app, devID, app.PlayerID, app.RecDuration));
            
            % If no mic was chosen
            if isempty(app.RecorderID)
                app.RecorderID = devID;
            end
            
            app.Recorder = audiorecorder(app.Fs, app.NBits, app.NumChannels, app.RecorderID);
            
            onOffComponents (app, 'on');
        end

        % Button pushed function: InputsettingButton
        function InputsettingButtonPushed(app, event)
            % Disable Buttons while dialog is open
            onOffComponents(app, 'off');
            micID = get(app.Recorder, "DeviceID"); % internal mic
            speakerID = app.PlayerID;
            recDuration = app.RecDuration;
            app.DialogApp = TunerSetting(app, micID, speakerID, recDuration);            
            waitfor(app.DialogApp);
            onOffComponents(app, 'on');
        end

        % Callback function: CloseButton, UIFigure
        function UIFigureCloseRequest(app, event)
            onOffComponents(app, 'off');
            if ~isempty(app.DialogApp)
                delete(app.DialogApp);
            end
            delete(app);   
        end

        % Button pushed function: A2Button, B3Button, D3Button, 
        % E2Button, E4Button, G3Button
        function E2ButtonPushed(app, event)
            note = event.Source.Text;
            app.CurrentNote = note;
            app.CurrentFrequency = setRefFreq(app);
            initializeGauge(app, app.CurrentFrequency);
            freq = note2freq(app, note);
            app.NOTELabel.Text = note;
            app.NOTELabel.FontColor = "g";
            %playFreq(app, freq);
            tune(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {631, 631};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {281, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.E2Button = uibutton(app.LeftPanel, 'push');
            app.E2Button.ButtonPushedFcn = createCallbackFcn(app, @E2ButtonPushed, true);
            app.E2Button.Text = 'E2';
            app.A2Button = uibutton(app.LeftPanel, 'push');
            app.A2Button.ButtonPushedFcn = createCallbackFcn(app, @E2ButtonPushed, true);
            app.A2Button.Text = 'A2';
            app.D3Button = uibutton(app.LeftPanel, 'push');
            app.D3Button.ButtonPushedFcn = createCallbackFcn(app, @E2ButtonPushed, true);
            app.D3Button.Text = 'D3';
            app.G3Button = uibutton(app.LeftPanel, 'push');
            app.G3Button.ButtonPushedFcn = createCallbackFcn(app, @E2ButtonPushed, true);
            app.G3Button.Text = 'G3';
            app.B3Button = uibutton(app.LeftPanel, 'push');
            app.B3Button.ButtonPushedFcn = createCallbackFcn(app, @E2ButtonPushed, true);
            app.B3Button.Text = 'B3';
            app.E4Button = uibutton(app.LeftPanel, 'push');
            app.E4Button.ButtonPushedFcn = createCallbackFcn(app, @E2ButtonPushed, true);
            app.E4Button.Text = 'E4';
            app.RightPanel = uipanel(app.GridLayout);
            app.NOTELabel = uilabel(app.RightPanel);
            app.NOTELabel.Text = 'NOTE';
            app.Gauge = uigauge(app.RightPanel, 'semicircular');
            app.InputsettingButton = uibutton(app.RightPanel, 'push');
            app.InputsettingButton.ButtonPushedFcn = createCallbackFcn(app, @InputsettingButtonPushed, true);
            app.InputsettingButton.Text = 'Input setting';
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CloseButton.Text = 'Close';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tuner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end