classdef TunerSetting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        InputdeviceDropDownLabel   matlab.ui.control.Label
        InputdeviceDropDown        matlab.ui.control.DropDown
        SettingLabel               matlab.ui.control.Label
        SaveButton                 matlab.ui.control.Button
        CancelButton               matlab.ui.control.Button
        RecordtimeSliderLabel      matlab.ui.control.Label
        RecordtimeSlider           matlab.ui.control.Slider
        OutputdeviceDropDownLabel  matlab.ui.control.Label
        OutputdeviceDropDown       matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, micID, speakerID, recDuration)
            app.CallingApp = mainapp; 
            app.RecordtimeSlider.Value = recDuration;
            
            app.InputdeviceDropDown.Items = {audiodevinfo().input().Name};
            micName = audiodevinfo(1, micID);
            app.InputdeviceDropDown.Value = micName;
            
            app.OutputdeviceDropDown.Items = {audiodevinfo().output().Name};
            speakerName = audiodevinfo(0, speakerID);
            app.OutputdeviceDropDown.Value = speakerName;  
        end

        % Callback function
        function InputdeviceDropDownValueChanged(app, event)
            %value = app.InputdeviceDropDown.Value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            micID = audiodevinfo(1, app.InputdeviceDropDown.Value);
            speakerID = audiodevinfo(0, app.OutputdeviceDropDown.Value);
            updateFromSettings(app.CallingApp, micID, speakerID, round(app.RecordtimeSlider.Value, 1));
            
            % Delete the dialog box
            UIFigureCloseRequest(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            UIFigureCloseRequest(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InputdeviceDropDownLabel = uilabel(app.UIFigure);
            app.InputdeviceDropDownLabel.Text = 'Input device';
            app.InputdeviceDropDown = uidropdown(app.UIFigure);
            app.InputdeviceDropDown.Items = {};
            app.InputdeviceDropDown.Value = {};
            app.SettingLabel = uilabel(app.UIFigure);
            app.SettingLabel.Text = 'Setting';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = {'Cancel'; ''};
            app.RecordtimeSliderLabel = uilabel(app.UIFigure);
            app.RecordtimeSliderLabel.Text = 'Record time';
            app.RecordtimeSlider = uislider(app.UIFigure);
            app.RecordtimeSlider.Limits = [0.1 2];
            app.RecordtimeSlider.MajorTicks = [0.5 1 1.5 2];
            app.RecordtimeSlider.MajorTickLabels = {'0.5', '1', '1.5', '2'};
            app.RecordtimeSlider.MinorTicks = [0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9];
            app.RecordtimeSlider.Value = 0.1;
            app.OutputdeviceDropDownLabel = uilabel(app.UIFigure);
            app.OutputdeviceDropDownLabel.Text = 'Output device';
            app.OutputdeviceDropDown = uidropdown(app.UIFigure);
            app.OutputdeviceDropDown.Items = {};
            app.OutputdeviceDropDown.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TunerSetting(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end